classdef SimpleDynamics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimpleDynamicsSimulationUIFigure  matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        BeamDropDownLabel              matlab.ui.control.Label
        BeamDropDown                   matlab.ui.control.DropDown
        TmatrixDropDownLabel           matlab.ui.control.Label
        TmatrixDropDown                matlab.ui.control.DropDown
        ShapeDropDownLabel             matlab.ui.control.Label
        ShapeDropDown                  matlab.ui.control.DropDown
        SimulateButton                 matlab.ui.control.Button
        Gauge                          matlab.ui.control.LinearGauge
        OutputVariableEditFieldLabel   matlab.ui.control.Label
        OutputVariableEditField        matlab.ui.control.EditField
        TabGroup                       matlab.ui.container.TabGroup
        FixedStepTab                   matlab.ui.container.Tab
        dxSpinnerLabel                 matlab.ui.control.Label
        dxSpinner                      matlab.ui.control.Spinner
        dthetaSpinnerLabel             matlab.ui.control.Label
        dthetaSpinner                  matlab.ui.control.Spinner
        FixedRateTab                   matlab.ui.container.Tab
        TimestepSpinnerLabel           matlab.ui.control.Label
        TimestepSpinner                matlab.ui.control.Spinner
        DragEditField_2Label           matlab.ui.control.Label
        DragEditField_2                matlab.ui.control.EditField
        MaxStepTab                     matlab.ui.container.Tab
        MaxdxSpinnerLabel              matlab.ui.control.Label
        MaxdxSpinner                   matlab.ui.control.Spinner
        MaxdthetaSpinnerLabel          matlab.ui.control.Label
        MaxdthetaSpinner               matlab.ui.control.Spinner
        MaxtimeSpinnerLabel            matlab.ui.control.Label
        MaxtimeSpinner                 matlab.ui.control.Spinner
        DragEditFieldLabel             matlab.ui.control.Label
        DragEditField                  matlab.ui.control.EditField
        NumStepsSpinnerLabel           matlab.ui.control.Label
        NumStepsSpinner                matlab.ui.control.Spinner
        InitialPositionEditFieldLabel  matlab.ui.control.Label
        InitialPositionEditField       matlab.ui.control.EditField
        InitialRotationEditFieldLabel  matlab.ui.control.Label
        InitialRotationEditField       matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        DropDown                       matlab.ui.control.DropDown
    end


    properties (Access = public)
        SimulationData % Data generated during a simulation
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SimulationData = [];
            
            % Look for beams in workspace
            varnames = evalin('base', 'who');
            beams = {};
            particles = {};
            shapes = {};
            for ii = 1:length(varnames)
                if isa(evalin('base', varnames{ii}), 'ott.Bsc')
                    beams{end+1} = varnames{ii};
                end
                if isa(evalin('base', varnames{ii}), 'ott.Tmatrix')
                    particles{end+1} = varnames{ii};
                end
                if isa(evalin('base', varnames{ii}), 'ott.shapes.Shape')
                    shapes{end+1} = varnames{ii};
                end
            end
            
            % Populate beam selector drop downs
            app.BeamDropDown.Items = beams;
            app.BeamDropDown.ItemsData = beams;
            app.BeamDropDown.Value = '';
            
            % Populate T-matrix selector drop down
            app.TmatrixDropDown.Items = particles;
            app.TmatrixDropDown.ItemsData = particles;
            app.TmatrixDropDown.Value = '';
            
            % Populate T-matrix selector drop down
            app.ShapeDropDown.Items = {'Shape: Axes', 'Shape: Cube', 'Shape: Sphere', shapes{:}};
            app.ShapeDropDown.ItemsData = {'Shape: Axes', 'Shape: Cube', 'Shape: Sphere', shapes{:}};
            app.ShapeDropDown.Value = 'Shape: Axes';
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Check there is work to do
            if isempty(app.SimulationData)
                return;
            end
            
            % TODO: Change what is displayed in the graph
            switch app.DropDown.Value
                case 'Position'
                case 'Rotation'
                case 'Force'
                case 'Torque'
                otherwise
                    error('OTT: Internal error: Invalid DropDown value');
            end
        end

        % Value changed function: OutputVariableEditField
        function OutputVariableEditFieldValueChanged(app, event)
            % TODO: Output data if simulation already run
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % TODO: run simulation
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimpleDynamicsSimulationUIFigure = uifigure;
            app.SimpleDynamicsSimulationUIFigure.Name = 'Simple Dynamics Simulation';
            app.Panel = uipanel(app.SimpleDynamicsSimulationUIFigure);
            app.BeamDropDownLabel = uilabel(app.Panel);
            app.BeamDropDownLabel.Text = 'Beam';
            app.BeamDropDown = uidropdown(app.Panel);
            app.BeamDropDown.Items = {};
            app.BeamDropDown.Editable = 'on';
            app.BeamDropDown.Value = {};
            app.TmatrixDropDownLabel = uilabel(app.Panel);
            app.TmatrixDropDownLabel.Text = 'T-matrix';
            app.TmatrixDropDown = uidropdown(app.Panel);
            app.TmatrixDropDown.Items = {};
            app.TmatrixDropDown.Editable = 'on';
            app.TmatrixDropDown.Value = {};
            app.ShapeDropDownLabel = uilabel(app.Panel);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.Panel);
            app.ShapeDropDown.Items = {'Shape: Axes', 'Shape: Cube', 'Shape: Sphere'};
            app.ShapeDropDown.ItemsData = {'Shape: Axes', 'Shape: Cube', 'Shape: Sphere'};
            app.ShapeDropDown.Editable = 'on';
            app.ShapeDropDown.Value = 'Shape: Axes';
            app.SimulateButton = uibutton(app.Panel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.Gauge = uigauge(app.Panel, 'linear');
            app.Gauge.Enable = 'off';
            app.OutputVariableEditFieldLabel = uilabel(app.Panel);
            app.OutputVariableEditFieldLabel.Text = 'Output Variable';
            app.OutputVariableEditField = uieditfield(app.Panel, 'text');
            app.OutputVariableEditField.ValueChangedFcn = createCallbackFcn(app, @OutputVariableEditFieldValueChanged, true);
            app.OutputVariableEditField.Value = 'SimulationData';
            app.TabGroup = uitabgroup(app.Panel);
            app.FixedStepTab = uitab(app.TabGroup);
            app.FixedStepTab.Title = 'Fixed Step';
            app.dxSpinnerLabel = uilabel(app.FixedStepTab);
            app.dxSpinnerLabel.Text = 'dx';
            app.dxSpinner = uispinner(app.FixedStepTab);
            app.dthetaSpinnerLabel = uilabel(app.FixedStepTab);
            app.dthetaSpinnerLabel.Text = 'dtheta';
            app.dthetaSpinner = uispinner(app.FixedStepTab);
            app.FixedRateTab = uitab(app.TabGroup);
            app.FixedRateTab.Title = 'Fixed Rate';
            app.TimestepSpinnerLabel = uilabel(app.FixedRateTab);
            app.TimestepSpinnerLabel.Text = 'Time step';
            app.TimestepSpinner = uispinner(app.FixedRateTab);
            app.TimestepSpinner.Step = 1e-05;
            app.TimestepSpinner.LowerLimitInclusive = 'off';
            app.TimestepSpinner.Limits = [0 Inf];
            app.TimestepSpinner.Value = 0.001;
            app.DragEditField_2Label = uilabel(app.FixedRateTab);
            app.DragEditField_2Label.Text = 'Drag';
            app.DragEditField_2 = uieditfield(app.FixedRateTab, 'text');
            app.DragEditField_2.Value = '{eye(3), eye(3)}';
            app.MaxStepTab = uitab(app.TabGroup);
            app.MaxStepTab.Title = 'Max Step';
            app.MaxdxSpinnerLabel = uilabel(app.MaxStepTab);
            app.MaxdxSpinnerLabel.Text = 'Max dx';
            app.MaxdxSpinner = uispinner(app.MaxStepTab);
            app.MaxdthetaSpinnerLabel = uilabel(app.MaxStepTab);
            app.MaxdthetaSpinnerLabel.Text = 'Max dtheta';
            app.MaxdthetaSpinner = uispinner(app.MaxStepTab);
            app.MaxtimeSpinnerLabel = uilabel(app.MaxStepTab);
            app.MaxtimeSpinnerLabel.Text = 'Max time';
            app.MaxtimeSpinner = uispinner(app.MaxStepTab);
            app.MaxtimeSpinner.Value = 1;
            app.DragEditFieldLabel = uilabel(app.MaxStepTab);
            app.DragEditFieldLabel.Text = 'Drag';
            app.DragEditField = uieditfield(app.MaxStepTab, 'text');
            app.DragEditField.Value = '{eye(3), eye(3)}';
            app.NumStepsSpinnerLabel = uilabel(app.Panel);
            app.NumStepsSpinnerLabel.Text = 'Num. Steps';
            app.NumStepsSpinner = uispinner(app.Panel);
            app.NumStepsSpinner.Value = 1000;
            app.InitialPositionEditFieldLabel = uilabel(app.Panel);
            app.InitialPositionEditFieldLabel.Text = 'Initial Position';
            app.InitialPositionEditField = uieditfield(app.Panel, 'text');
            app.InitialPositionEditField.Value = '[0, 0, 0]';
            app.InitialRotationEditFieldLabel = uilabel(app.Panel);
            app.InitialRotationEditFieldLabel.Text = 'Initial Rotation';
            app.InitialRotationEditField = uieditfield(app.Panel, 'text');
            app.InitialRotationEditField.Value = 'eye(3)';
            app.UIAxes = uiaxes(app.SimpleDynamicsSimulationUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.SimpleDynamicsSimulationUIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.DropDown = uidropdown(app.SimpleDynamicsSimulationUIFigure);
            app.DropDown.Items = {'Position', 'Rotation', 'Force', 'Torque'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Position';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SimpleDynamics

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimpleDynamicsSimulationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimpleDynamicsSimulationUIFigure)
        end
    end
end